classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CSTB_RAMANUIFigure              matlab.ui.Figure
        IdentifcationduspectrePanel     matlab.ui.container.Panel
        CorrlaltionLabel                matlab.ui.control.Label
        Corr_Gauge                      matlab.ui.control.Gauge
        ProcessButton                   matlab.ui.control.Button
        label_text                      matlab.ui.control.Label
        RestartButton                   matlab.ui.control.Button
        ChooseaFileButton               matlab.ui.control.Button
        ByIbrahimELGHARBILabel          matlab.ui.control.Label
        FilefromBDCHARLESSwitchLabel    matlab.ui.control.Label
        Switch                          matlab.ui.control.ToggleSwitch
        ChoixIdentificationSwitchLabel  matlab.ui.control.Label
        ChoixIdentificationSwitch       matlab.ui.control.ToggleSwitch
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        RfrenceLabel                    matlab.ui.control.Label
        ComposLabel                     matlab.ui.control.Label
    end


    properties (Access = public)
        n_initial ; 
        t_initial_golay ;
        nom_elements={'Actinotite_2','Amosite_1','Amosite_2','Amosite_3','Amosite_4','Carton_1','chrysotile','Chrysotile_2','Chrysotile_3','ciment_1','crocidolite_1','crocidolite_4','crocodolite_3','crocodolite_4','DalenoireEch42','Flocage_Ec','Flocage_Ech26','INRS_Ch4_4','INRS_Ech1_2','Joint_Ech13','Joint_Ech13_2','jointradiateurEch10_2','plaque murail_4__Ech51','plaque murail_Ech','Plaque_45_1','trenolite_1','Tresse_Ech5_4'}
        nom_elements_ref={'Actinotite','Amosite','Antophilite','Chrysotile','Chrysotile_HLS_Canada','Chrysotile_Spi_Canada','Chrysotile_Spi_Rhodésie','Chrysotile_Spi_Zimbabwe','Crocidolite','tremolite'} ;
        
        % Description
    end

    methods (Access = private)
    
        function results = correlation_calculator(app, t_initial,n_init)
            value1 = app.ChoixIdentificationSwitch.Value;
            if (strcmp(value1,'1')==0)
                path_corr='C:\Users\LEILA\Desktop\STAGE CSTB\BD SPECTRE 2\Spectre BD sans nom' ;    
                id1=1:27;
            end
            if (strcmp(value1,'2')==0)
                path_corr='C:\Users\LEILA\Desktop\STAGE CSTB\BD SPECTRE 2\Spectre BD sans nom pur' ;
                id1=1:10;
            end
            
            for id=id1
                chr=int2str(id) ;
                chr_file=strcat(chr,'.txt') ;
                
                fid = fopen(fullfile(path_corr,chr_file),'rt');
                A = textscan(fid, '%f %f', 'HeaderLines',2);
                fclose(fid);
                n=A{1}  ; 
                y=A{2} ;
                
                
                [z,a,it] = backcor(n,y,10,2*rms(y),'sh');
                t=y-z ; 
                t_golay=sgolayfilt(t,3,11) ;
                for i=1:size(n)
                    if t_golay(i,1)<=0
                        t_golay(i,1)=0 ;
                    end
                end
                interpolation=interp1(n,t_golay,n_init,'linear','extrap') ;
                CO=corr(interpolation,t_initial) ;
                C(id)=CO ;
            end
            results=C ;
        end
        
        function results = correlation_calculator2(app,t_initial,n_init)
            value1 = app.ChoixIdentificationSwitch.Value;
            if (value1=='1')
                path_corr='C:\Users\LEILA\Desktop\STAGE CSTB\BD SPECTRE 2\Spectre BD sans nom' ; 
                id1=1:27 ; 
            end
            if (value1=='2')
                path_corr='C:\Users\LEILA\Desktop\STAGE CSTB\BD SPECTRE 2\Spectre BD sans nom pur' ;
                id1=1:10 ;
            end
            for id=id1
                chr=int2str(id) ;
                chr_file=strcat(chr,'.txt') ;
                
                fid = fopen(fullfile(path_corr,chr_file),'rt');
                A = textscan(fid, '%f %f', 'HeaderLines',2);
                fclose(fid);
                n=A{1} ; 
                y=A{2} ;
                
                
                [z,a,it] = backcor(n,y,10,2*rms(y),'sh');
                t=y-z ; 
                t_golay=sgolayfilt(t,3,11) ;
                for i=1:size(n)
                    if t_golay(i,1)<=0
                        t_golay(i,1)=0 ;
                    end
                end
                interpolation=interp1(n,t_golay,n_init,'linear','extrap') ;
                CO=corr(interpolation,t_initial) ;
                C(id)=CO ;
            end
            results=C ;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            imshow('cstb_logo.png','parent',app.UIAxes2) ;
            title(app.UIAxes2,[]);
            xlabel(app.UIAxes2,[]) ;
            ylabel(app.UIAxes2,[]); 
            app.UIAxes2.XAxis.TickLabels={} ;
            app.UIAxes2.YAxis.TickLabels={} ;

        end

        % Button pushed function: ChooseaFileButton
        function ChooseaFileButtonPushed(app, event)
            value = app.Switch.Value;
            if (value=='Macro')
                %                   choose a file in a dialog
                [file, path]=uigetfile({'*.txt;*.dat'}) ;
                fid = fopen(fullfile(path,file),'rt');
                A = textscan(fid, '%f %f', 'HeaderLines',2);
                fclose(fid);
                % plot(A{1},A{2});
                n=A{1};
                y=A{2} ;
                
                sigx = 1;
                threashold=2*rms(y) ;
                [z,a,it] = backcor(n,y,10,threashold,'sh');
                %               background = z / max(z) * sigx;
                t=y-z ;
                t=imgaussfilt(y-z) ;
                t_golay=sgolayfilt(t,3,11) ;
                t_golay=normalize(t_golay) ;
                for i=1:size(n)
                    if t_golay(i,1)<=2*rms(t_golay)
                        t_golay(i,1)=0 ;
                    end
                end
                
                plot(app.UIAxes,n,t_golay,'b') ;
                app.t_initial_golay=t_golay ;
                
                app.n_initial=n ;
            end
            if (value=='Micro')
%                 choose a file in a dialog
                [file, path]=uigetfile({'*.txt;*.dat'}) ;
                fid = fopen(fullfile(path,file),'rt');
                A = textscan(fid, '%f %f', 'HeaderLines',2);
                fclose(fid);
                % plot(A{1},A{2});
                n=A{1} ;
                y=A{2} ;
                
                sigx = 1;
                threashold=2*rms(y) ;
                [z,a,it] = backcor(n,y,10,threashold,'sh');
                %               background = z / max(z) * sigx;
                t=y-z ;
                t=imgaussfilt(y-z) ;
                t_golay=sgolayfilt(t,3,11) ;
                t_golay=normalize(t_golay) ;
                for i=1:size(n)
                    if t_golay(i,1)<=2*rms(t_golay)
                        t_golay(i,1)=0 ;
                    end
                end
                
                plot(app.UIAxes,n,t_golay,'b') ;
                app.t_initial_golay=t_golay ;
            end
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            value = app.Switch.Value;
            if (value=='Macro')
                Corr_process=correlation_calculator2(app,app.t_initial_golay,app.n_initial) ;
            end
            if (value=='Micro')
                Corr_process=correlation_calculator(app,app.t_initial_golay,app.n_initial) ;
            end
             
            [M,id]=max(Corr_process(:)) ; 
            value1 = app.ChoixIdentificationSwitch.Value;
            if (value1=='1')
                chr= app.nom_elements(id);      
            end
            if (value1=='2')
                chr= app.nom_elements_ref(id);
            end
            
            app.label_text.Text=chr ;
            app.Corr_Gauge.Value=M*100 ;

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
              
        end

        % Value changed function: ChoixIdentificationSwitch
        function ChoixIdentificationSwitchValueChanged(app, event)
       
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CSTB_RAMANUIFigure = uifigure;
            app.CSTB_RAMANUIFigure.Name = 'CSTB_RAMAN';
            app.IdentifcationduspectrePanel = uipanel(app.CSTB_RAMANUIFigure);
            app.IdentifcationduspectrePanel.Title = 'Identifcation du spectre';
            app.CorrlaltionLabel = uilabel(app.IdentifcationduspectrePanel);
            app.CorrlaltionLabel.Text = 'Corrélaltion %';
            app.Corr_Gauge = uigauge(app.IdentifcationduspectrePanel, 'circular');
            app.ProcessButton = uibutton(app.IdentifcationduspectrePanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.label_text = uilabel(app.IdentifcationduspectrePanel);
            app.label_text.Text = '';
            app.RestartButton = uibutton(app.CSTB_RAMANUIFigure, 'push');
            app.RestartButton.Text = 'Restart';
            app.ChooseaFileButton = uibutton(app.CSTB_RAMANUIFigure, 'push');
            app.ChooseaFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseaFileButtonPushed, true);
            app.ChooseaFileButton.Text = 'Choose a File';
            app.ByIbrahimELGHARBILabel = uilabel(app.CSTB_RAMANUIFigure);
            app.ByIbrahimELGHARBILabel.Text = 'By Ibrahim EL GHARBI ';
            app.FilefromBDCHARLESSwitchLabel = uilabel(app.CSTB_RAMANUIFigure);
            app.FilefromBDCHARLESSwitchLabel.Text = 'File from BD CHARLES';
            app.Switch = uiswitch(app.CSTB_RAMANUIFigure, 'toggle');
            app.Switch.Items = {'Micro', 'Macro'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Macro';
            app.ChoixIdentificationSwitchLabel = uilabel(app.CSTB_RAMANUIFigure);
            app.ChoixIdentificationSwitchLabel.Text = 'Choix Identification';
            app.ChoixIdentificationSwitch = uiswitch(app.CSTB_RAMANUIFigure, 'toggle');
            app.ChoixIdentificationSwitch.Items = {'2', '1'};
            app.ChoixIdentificationSwitch.ValueChangedFcn = createCallbackFcn(app, @ChoixIdentificationSwitchValueChanged, true);
            app.ChoixIdentificationSwitch.Value = '2';
            app.UIAxes = uiaxes(app.CSTB_RAMANUIFigure);
            title(app.UIAxes, 'Spectre')
            xlabel(app.UIAxes, 'Fréquence')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.CSTB_RAMANUIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.RfrenceLabel = uilabel(app.CSTB_RAMANUIFigure);
            app.RfrenceLabel.Text = 'Référence';
            app.ComposLabel = uilabel(app.CSTB_RAMANUIFigure);
            app.ComposLabel.Text = 'Composé';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CSTB_RAMANUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CSTB_RAMANUIFigure)
        end
    end
end